classdef app06092020keyboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UserOutputsPanel               matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        EndEffectorPositionMetrePanel  matlab.ui.container.Panel
        XPositionEditFieldLabel        matlab.ui.control.Label
        XPositionEditField             matlab.ui.control.NumericEditField
        YPositionEditFieldLabel        matlab.ui.control.Label
        YPositionEditField             matlab.ui.control.NumericEditField
        ZPositionEditFieldLabel        matlab.ui.control.Label
        ZPositionEditField             matlab.ui.control.NumericEditField
        wrtSpacecraftLabel             matlab.ui.control.Label
        EndEffectorAnglesRadianPanel   matlab.ui.container.Panel
        PitchbetaEditFieldLabel        matlab.ui.control.Label
        PitchbetaEditField             matlab.ui.control.NumericEditField
        YawgammaEditFieldLabel         matlab.ui.control.Label
        YawgammaEditField              matlab.ui.control.NumericEditField
        wrtSpacecraftLabel_2           matlab.ui.control.Label
        RollalphaEditFieldLabel        matlab.ui.control.Label
        RollalphaEditField             matlab.ui.control.NumericEditField
        ArmJointAnglesRadPanel         matlab.ui.container.Panel
        theta1EditFieldLabel           matlab.ui.control.Label
        theta1EditField                matlab.ui.control.NumericEditField
        theta2EditFieldLabel           matlab.ui.control.Label
        theta2EditField                matlab.ui.control.NumericEditField
        theta4EditFieldLabel           matlab.ui.control.Label
        theta4EditField                matlab.ui.control.NumericEditField
        theta5EditFieldLabel           matlab.ui.control.Label
        theta5EditField                matlab.ui.control.NumericEditField
        theta6EditFieldLabel           matlab.ui.control.Label
        theta6EditField                matlab.ui.control.NumericEditField
        theta3EditFieldLabel           matlab.ui.control.Label
        theta3EditField                matlab.ui.control.NumericEditField
        UserInputsPanel                matlab.ui.container.Panel
        EndEffectorRotationwrtSpacecraftPanel  matlab.ui.container.Panel
        Button_7                       matlab.ui.control.Button
        Button_9                       matlab.ui.control.Button
        Button_12                      matlab.ui.control.Button
        Button_11                      matlab.ui.control.Button
        Button_10                      matlab.ui.control.Button
        Button_8                       matlab.ui.control.Button
        AngleradSpinner                matlab.ui.control.Spinner
        AngleradLabel                  matlab.ui.control.Label
        EndEffectorDirectionwrtSpacecraftPanel  matlab.ui.container.Panel
        Button                         matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button_5                       matlab.ui.control.Button
        Button_6                       matlab.ui.control.Button
        VelocitymsSpinner              matlab.ui.control.Spinner
        VelocitymsLabel                matlab.ui.control.Label
        GripperControlPanel            matlab.ui.container.Panel
        Lamp                           matlab.ui.control.Lamp
        VelocitymsSpinner_2            matlab.ui.control.Spinner
        Label_2                        matlab.ui.control.Label
        VelocitymsLabel_2              matlab.ui.control.Label
        GripperSwitch_2Label           matlab.ui.control.Label
        GripperSwitch_2                matlab.ui.control.Switch
        ConnectionPanel                matlab.ui.container.Panel
        ConnectionSwitch               matlab.ui.control.RockerSwitch
        Lamp_2                         matlab.ui.control.Lamp
        InputDeviceButtonGroup         matlab.ui.container.ButtonGroup
        Label                          matlab.ui.control.Label
        KeyboardSwitch                 matlab.ui.control.Switch
        KeyboardLabel                  matlab.ui.control.Label
        JoystickSwitch                 matlab.ui.control.Switch
        Label_3                        matlab.ui.control.Label
        JoystickLabel                  matlab.ui.control.Label
        UserGuideButton                matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        sim % Description
        clientID
        TargetSrvID
        TargetSrvPos% Description
        TargetSrvOri
        EndGripper
        VisionID
        Image
        Resolution
        h
        j
        Vel_value
        Vision_depthID
        Image_depth
        Resolution0
        SpacecraftID
        L
        Rob
        J
        T
        PX, PY, PZ
        Force
        fig
        ID
        joy
        Y
        X
        gripper
    end
    
    
    methods (Access = private)
        
        function getPosition(app)
            app.XPositionEditField.Value = double(app.TargetSrvPos(1));
            app.YPositionEditField.Value = double(app.TargetSrvPos(2));
            app.ZPositionEditField.Value = double(app.TargetSrvPos(3));
        end
        
        function getAngle(app)
%             app.RollalphaEditField.Value = double(app.j(1));
%             app.PitchbetaEditField.Value = double(app.j(2));
%             app.YawgammaEditField.Value = double(app.j(3));
%             app.theta4EditField.Value = double(app.j(4));
%             app.theta5EditField.Value = double(app.j(5));
%             app.theta6EditField.Value = double(app.j(6));
            app.RollalphaEditField.Value = double(app.TargetSrvOri(1));
            app.PitchbetaEditField.Value = double(app.TargetSrvOri(2));
            app.YawgammaEditField.Value = double(app.TargetSrvOri(3));
        end
        
        function getJointAngle(app)
            app.theta1EditField.Value = double(app.j(1));
            app.theta2EditField.Value = double(app.j(2));
            app.theta3EditField.Value = double(app.j(3));
            app.theta4EditField.Value = double(app.j(4));
            app.theta5EditField.Value = double(app.j(5));
            app.theta6EditField.Value = double(app.j(6));
%             app.RollalphaEditField.Value = double(app.TargetSrvOri(1));
%             app.PitchbetaEditField.Value = double(app.TargetSrvOri(2));
%             app.YawgammaEditField.Value = double(app.TargetSrvOri(3));
        end
        
        
        function updateImages(app)
                     [returnCode,app.Resolution,app.Image]= app.sim.simxGetVisionSensorImage2(app.clientID,app.VisionID,0,app.sim.simx_opmode_buffer);
             [returnCode,app.Resolution0,app.Image_depth]= app.sim.simxGetVisionSensorImage2(app.clientID,app.Vision_depthID,0,app.sim.simx_opmode_buffer);
             imshow(app.Image, 'Parent', app.UIAxes);
             imshow(app.Image_depth, 'Parent', app.UIAxes_2);
        
        end
        
%         function keyboardInputMode(app)
%             S.fh = uifigure('units','pixels',...
%                           'position',[500 500 220 180],...
%                           'menubar','none',...
%                           'name','move_fig',...
%                           'numbertitle','off',...
%                           'resize','on',...
%                           'keypressfcn',@fh_kpfcn);
%           
%             S.tx = uicontrol('style','text',...
%                              'units','pixels',...
%                              'position',[60 120 80 40],...
%                              'fontweight','bold'); 
%             guidata(S.fh,S)    
%             
%             function []= fh_kpfcn(H,E)          
%             % Figure keypressfcn
%             S = guidata(H);
%             %P = get(S.fh,'position');
%             set(S.tx,'string',E.Key)
%                 switch E.Key
%                     case 'uparrow'
%                         ButtonPushed(app);
%                         
%                     case 'downarrow'
%                         Button_4Pushed(app);
%                         
%                     case 'leftarrow'
%                         Button_3Pushed(app);
%                         
%                     case 'rightarrow'
%                         Button_2Pushed(app);
%                         
%                     case 'w'
%                         Button_5Pushed(app);
%                     
%                     %go backward on y axis  
%                     case 's'
%                         Button_6Pushed(app);
%                         
%                     case 'q'
%                         Button_7Pushed(app);
%     
%                     case 'e'
%                         Button_9Pushed(app);
%                         
%                     case 'a'
%                         Button_12Pushed(app);
%                         
%                     case 'd'
%                         Button_11Pushed(app);
%                         
%                     case 'z'
%                         Button_10Pushed(app);
%                         
%                     case 'c'
%                         Button_8Pushed(app);
%                 end
%             end
%         end
        

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ConnecttoRobotButtonPushed(app, event)
%             app.sim=remApi('remoteApi'); % using the prototype file (remoteApiProto.m)
%             app.sim.simxFinish(-1); % just in case, close all opened connections
%             app.clientID=app.sim.simxStart('127.0.0.1',19999,true,true,5000,5);
%             
%             
%             [r,app.TargetSrvID]=app.sim.simxGetObjectHandle(app.clientID,'TargetSrv',app.sim.simx_opmode_oneshot_wait);
%             [res, app.TargetSrvPos] = app.sim.simxGetObjectPosition(app.clientID, app.TargetSrvID, -1,app.sim.simx_opmode_oneshot_wait); 
%             [res, app.TargetSrvOri] = app.sim.simxGetObjectOrientation(app.clientID, app.TargetSrvID, -1,app.sim.simx_opmode_oneshot_wait); 
%             [r, app.EndGripper] = app.sim.simxGetObjectHandle(app.clientID,'RG2_openCloseJoint', app.sim.simx_opmode_oneshot_wait); 
%             display('Connected!')
%             
%             getPosition(app);
%             getAngle(app)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [errorCode]=app.sim.simxSetObjectPosition(app.clientID,app.TargetSrvID,app.SpacecraftID,[app.TargetSrvPos(1)+app.VelocitymsSpinner.Value app.TargetSrvPos(2) app.TargetSrvPos(3)],app.sim.simx_opmode_oneshot);

            [res, app.TargetSrvPos] = app.sim.simxGetObjectPosition(app.clientID, app.TargetSrvID, app.SpacecraftID,app.sim.simx_opmode_blocking);  
            for i=1:6
                [res,app.j(i)]=app.sim.simxGetJointPosition(app.clientID,app.h(i),app.sim.simx_opmode_oneshot_wait);
            end
            
            updateImages(app); 
            getPosition(app);
            getAngle(app);
            getJointAngle(app);
            %pause(2);
            figure(1)
            app.Rob.plot(app.j);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            [errorCode]=app.sim.simxSetObjectPosition(app.clientID,app.TargetSrvID,app.SpacecraftID,[app.TargetSrvPos(1)-app.VelocitymsSpinner.Value app.TargetSrvPos(2) app.TargetSrvPos(3)],app.sim.simx_opmode_oneshot);

            [res, app.TargetSrvPos] = app.sim.simxGetObjectPosition(app.clientID, app.TargetSrvID, app.SpacecraftID,app.sim.simx_opmode_blocking); 
            for i=1:6
                [res,app.j(i)]=app.sim.simxGetJointPosition(app.clientID,app.h(i),app.sim.simx_opmode_oneshot_wait);
            end
            
            updateImages(app); 
            getPosition(app);
            getAngle(app);
            getJointAngle(app);
            %pause(2);
            figure(1)
            app.Rob.plot(app.j);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
%             app.leftbtn = uibutton(app.UIFigure, 'state',...
%                 "Text", "Left");
%             
%             while app.leftbtn.Value~=1
                       
                [errorCode]=app.sim.simxSetObjectPosition(app.clientID,app.TargetSrvID,app.SpacecraftID,[app.TargetSrvPos(1) app.TargetSrvPos(2) app.TargetSrvPos(3)-app.VelocitymsSpinner.Value],app.sim.simx_opmode_oneshot);
    
                [res, app.TargetSrvPos] = app.sim.simxGetObjectPosition(app.clientID, app.TargetSrvID, app.SpacecraftID,app.sim.simx_opmode_blocking); 
                for i=1:6
                    [res,app.j(i)]=app.sim.simxGetJointPosition(app.clientID,app.h(i),app.sim.simx_opmode_oneshot_wait);
                end
                
                updateImages(app); 
                getPosition(app);
                getAngle(app);
                getJointAngle(app);
                %pause(2);
                figure(1)
                app.Rob.plot(app.j);
%             end
%             app.leftbtn.Value = 0
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [errorCode]=app.sim.simxSetObjectPosition(app.clientID,app.TargetSrvID,app.SpacecraftID,[app.TargetSrvPos(1) app.TargetSrvPos(2) app.TargetSrvPos(3)+ app.VelocitymsSpinner.Value],app.sim.simx_opmode_oneshot);

            
            [res, app.TargetSrvPos] = app.sim.simxGetObjectPosition(app.clientID, app.TargetSrvID, app.SpacecraftID,app.sim.simx_opmode_blocking); 
            for i=1:6
                [res,app.j(i)]=app.sim.simxGetJointPosition(app.clientID,app.h(i),app.sim.simx_opmode_oneshot_wait);
            end
            
            updateImages(app); 
            getPosition(app);  
            getAngle(app);
            getJointAngle(app);
            %pause(2);
            figure(1)
            app.Rob.plot(app.j);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            [errorCode]=app.sim.simxSetObjectPosition(app.clientID,app.TargetSrvID,app.SpacecraftID,[app.TargetSrvPos(1) app.TargetSrvPos(2)-0.01 app.TargetSrvPos(3)],app.sim.simx_opmode_oneshot);

            [res, app.TargetSrvPos] = app.sim.simxGetObjectPosition(app.clientID, app.TargetSrvID, app.SpacecraftID,app.sim.simx_opmode_blocking); 
            for i=1:6
                [res,app.j(i)]=app.sim.simxGetJointPosition(app.clientID,app.h(i),app.sim.simx_opmode_oneshot_wait);
            end    
            
            updateImages(app); 
            getPosition(app);
            getAngle(app);
            getJointAngle(app);
            %pause(2);
            figure(1)
            app.Rob.plot(app.j);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            [errorCode]=app.sim.simxSetObjectPosition(app.clientID,app.TargetSrvID,app.SpacecraftID,[app.TargetSrvPos(1) app.TargetSrvPos(2)+0.01 app.TargetSrvPos(3)],app.sim.simx_opmode_oneshot);

            [res, app.TargetSrvPos] = app.sim.simxGetObjectPosition(app.clientID, app.TargetSrvID, app.SpacecraftID,app.sim.simx_opmode_blocking); 
            for i=1:6
                [res,app.j(i)]=app.sim.simxGetJointPosition(app.clientID,app.h(i),app.sim.simx_opmode_oneshot_wait);
            end            
            
            updateImages(app); 
            getPosition(app);
            getAngle(app);
            getJointAngle(app);
            
            figure(1)
            app.Rob.plot(app.j);
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            %value = app.EditField.Value;
            app.LeftGauge.Value = app.EditField.Value
            if app.EditField.Value >= 180
               % prompt = {'Enter value for x'};
                msgbox('Rotation should be less than 180','Invalid Input');
            end
        end

        % Value changed function: ConnectionSwitch
        function ConnectionSwitchValueChanged(app, event)
            value = app.ConnectionSwitch.Value;
            vel_value = app.VelocitymsSpinner.Value;
                       
            app.Lamp_2.Color = 'r';
            
            app.Button.Enable = true;
            app.Button_4.Enable = true;
            app.Button_3.Enable = true;
            app.Button_2.Enable = true;
            app.Button_5.Enable = true;
            app.Button_6.Enable = true;
            app.VelocitymsSpinner.Enable = true;
            app.Button_7.Enable = true;
            app.Button_9.Enable = true;
            app.Button_12.Enable = true;
            app.Button_11.Enable = true;
            app.Button_10.Enable = true;
            app.Button_8.Enable = true;
            app.AngleradSpinner.Enable = true;
            app.AngleradLabel.Enable = true;
            app.GripperSwitch_2.Enable =true; 
            app.VelocitymsLabel_2.Enable = true;
            app.Lamp.Enable = true;
            app.VelocitymsSpinner_2.Enable = true;
            app.VelocitymsLabel.Enable = true;
            app.Label_2.Enable = true;
            app.Label.Enable = true;
            app.KeyboardSwitch.Enable = true;
            app.KeyboardLabel.Enable = true;
            app.JoystickLabel.Enable = true;
            app.JoystickSwitch.Enable = true;
            app.Label_3.Enable = true;
            
            if strcmp(value, 'On');
                app.sim=remApi('remoteApi'); % using the prototype file (remoteApiProto.m)
                app.sim.simxFinish(-1); % just in case, close all opened connections
                app.clientID=app.sim.simxStart('127.0.0.1',19999,true,true,5000,5);
                
% %                 Get Object Handle
                [r,app.h(1)]=app.sim.simxGetObjectHandle(app.clientID,'UR10_joint1',app.sim.simx_opmode_oneshot_wait);
                [r,app.h(2)]=app.sim.simxGetObjectHandle(app.clientID,'UR10_joint2',app.sim.simx_opmode_oneshot_wait);
                [r,app.h(3)]=app.sim.simxGetObjectHandle(app.clientID,'UR10_joint3',app.sim.simx_opmode_oneshot_wait);
                [r,app.h(4)]=app.sim.simxGetObjectHandle(app.clientID,'UR10_joint4',app.sim.simx_opmode_oneshot_wait);
                [r,app.h(5)]=app.sim.simxGetObjectHandle(app.clientID,'UR10_joint5',app.sim.simx_opmode_oneshot_wait);
                [r,app.h(6)]=app.sim.simxGetObjectHandle(app.clientID,'UR10_joint6',app.sim.simx_opmode_oneshot_wait);
                                      

                [r,app.TargetSrvID]=app.sim.simxGetObjectHandle(app.clientID,'TargetSrv',app.sim.simx_opmode_oneshot_wait);
                [r,app.SpacecraftID] = app.sim.simxGetObjectHandle(app.clientID,'Cuboid0',app.sim.simx_opmode_oneshot_wait);
                [r, app.EndGripper] = app.sim.simxGetObjectHandle(app.clientID,'RG2_openCloseJoint', app.sim.simx_opmode_oneshot_wait); 
                
                [res, app.TargetSrvPos] = app.sim.simxGetObjectPosition(app.clientID, app.TargetSrvID, app.SpacecraftID,app.sim.simx_opmode_oneshot_wait); 
                %[res, app.TargetSrvPos] = app.sim.simxGetObjectPosition(app.clientID, app.TargetSrvID, app.SpacecraftID,app.sim.simx_opmode_oneshot_wait); 
                [res, app.TargetSrvOri] = app.sim.simxGetObjectOrientation(app.clientID, app.TargetSrvID, app.SpacecraftID,app.sim.simx_opmode_oneshot_wait); 
                %[res, app.TargetSrvOri] = app.sim.simxGetObjectOrientation(app.clientID, app.TargetSrvID, app.SpacecraftID,app.sim.simx_opmode_oneshot_wait); 
                
                %read gripper force
%                 [res, app.EndGripperData] = app.sim.simxSetJointForce(app.clientID, app.EndGripper, app.sim.simx_opmode_oneshot_wait); 
                
                
                [r,app.VisionID] = app.sim.simxGetObjectHandle(app.clientID,'Vision_sensor_RGB',app.sim.simx_opmode_oneshot_wait);
                [r,app.Vision_depthID] = app.sim.simxGetObjectHandle(app.clientID,'Vision_sensor_depth',app.sim.simx_opmode_oneshot_wait);
                for i=1:6
                    [res,app.j(i)]=app.sim.simxGetJointPosition(app.clientID,app.h(i),app.sim.simx_opmode_oneshot_wait);
                end
%                 
                [returnCode,app.Resolution,app.Image]= app.sim.simxGetVisionSensorImage2(app.clientID,app.VisionID,0,app.sim.simx_opmode_streaming);
                [returnCode,app.Resolution0,app.Image_depth]= app.sim.simxGetVisionSensorImage2(app.clientID,app.Vision_depthID,0,app.sim.simx_opmode_streaming);

                if strcmp(app.GripperSwitch_2.Value, 'Close')
                    app.gripper = 'close';
                end
                disp('Connected!')
                disp(double(app.VelocitymsSpinner.Value))
                disp(value)
                getPosition(app);
                getAngle(app);
                getJointAngle(app);
                
%                 if (app.KeyboardButton.Value)
%                     keyboardInputMode(app);
%                 elseif (app.JoystickButton.Value)
%                     %joystick operation
%                 else
%                     % do nothing
%                 end
                
                app.Lamp_2.Color = 'g';
                
                %% 3D Graph for arm motion
%                 app.L = Link();
%                 app.L(1) = Link([0 0.089159 0.000000 pi/2]);
%                 app.L(2) = Link([0 0.000000 -0.42500 0]);
%                 app.L(3) = Link([0 0.000000 -0.39225 0]);
%                 app.L(4) = Link([0 0.109150 0.000000 pi/2]);
%                 app.L(5) = Link([0 0.094650 0.000000 -(pi/2)]);
%                 app.L(6) = Link([0 0.082300 0.000000 0.000000]);
%                 app.Rob = SerialLink(app.L);
%                 app.Rob.name = 'UR5';
%                 
                app.L = Link();
                app.L(1) = Link([0 0.127300 0.000000 pi/2]);
                app.L(2) = Link([0 0.000000 -0.61200 0]);
                app.L(3) = Link([0 0.000000 -0.57230 0]);
                app.L(4) = Link([0 0.163941 0.000000 pi/2]);
                app.L(5) = Link([0 0.115700 0.000000 -(pi/2)]);
                app.L(6) = Link([0 0.092200 0.000000 0.000000]);
                app.Rob = SerialLink(app.L);
                app.Rob.name = 'UR10';


% 
%                 app.PX = app.XPositionEditField.Value
%                 app.PY = app.YPositionEditField.Value
%                 app.PZ = app.ZPositionEditField.Value
%                 
%                 app.T = [ 1 0 0 app.PX;
%                       0 1 0 app.PY;
%                       0 0 1 app.PZ;
%                       0 0 0 1];
%                app.J = app.Rob.ikine(app.T, [0 0 0 0 0 0],'mask', [1 1 1 0 0 0]) * 180/pi;
% %                 app.J = app.Rob.ikine(app.T, [0 0 0 0 0 0]);
%                     handles.Theta_1.String = num2str(floor(J(1)));
%                     handles.Theta_2.String = num2str(floor(J(2)));
%                     handles.Theta_3.String = num2str(floor(J(3)));
%                 app.J = [0 0 0 0 0 0];
%                 figure(1)
                figure(1)
                set(figure(1),'Position', [1180 412 350 350])
                title('UR10 Simulation in 3D Graph');
                                             
                app.Rob.plot([app.j]);
                
%                 legend('axes are in metre','Location','northeast')
                xlabel('X (meter)');
                ylabel('Y (meter)');
                zlabel('Z (meter)');
%                 view([90 -13]);
%                 app.Rob.plot("Parent",app.j)
%                 app.Rob.plot([app.j], app.UIAxes2);
%                 app.Rob.plot([app.j], app.UIAxes2)
%                 fig = uifigure('Position',[100 100 752 250]);
%                 uit = uitable('Parent',fig,'Position',[25 50 700 200]);
                %app.Rob.teach
                
                
                  while 1
%                 for i =1:50
                     [returnCode,app.Resolution,app.Image]= app.sim.simxGetVisionSensorImage2(app.clientID,app.VisionID,0,app.sim.simx_opmode_buffer);
                     [returnCode,app.Resolution0,app.Image_depth]= app.sim.simxGetVisionSensorImage2(app.clientID,app.Vision_depthID,0,app.sim.simx_opmode_buffer);
                     imshow(app.Image, 'Parent', app.UIAxes);
                     imshow(app.Image_depth, 'Parent', app.UIAxes_2);
                     c = colorbar(app.UIAxes_2); 
                     colormap(app.UIAxes_2, jet); 
                     c.Label.String = 'Color Label'; 
                     app.UIAxes_2.FontSize = 10; 
                      pause(0.1);
                 end 


            else
                app.sim.simxFinish(app.clientID);
                app.sim.delete(); % call the destructor!    
                disp('Program ended');
                app.Lamp_2.Color = 'r';
                app.Button.Enable = false;
                app.Button_4.Enable = false;
                app.Button_3.Enable = false;
                app.Button_2.Enable = false;
                app.Button_5.Enable = false;
                app.Button_6.Enable = false;
                app.VelocitymsSpinner.Enable = false;
                app.VelocitymsLabel.Enable = false;
                app.Button_7.Enable = false;
                app.Button_9.Enable = false;
                app.Button_12.Enable = false;
                app.Button_11.Enable = false;
                app.Button_10.Enable = false;
                app.Button_8.Enable = false;
                app.AngleradSpinner.Enable = false;
                app.AngleradLabel.Enable = false;
                app.GripperSwitch_2.Enable =false; 
                app.VelocitymsLabel_2.Enable = false;
                app.Lamp.Enable = false;
                app.VelocitymsSpinner_2.Enable = false;
                app.VelocitymsLabel.Enable = false;
                app.Label_2.Enable = false;
                app.Label.Enable = false;
                app.KeyboardSwitch.Enable = false;
                app.KeyboardLabel.Enable = false;
                app.JoystickSwitch.Enable = false;
                app.JoystickLabel.Enable = false;
                app.Label_3.Enable = false;
            end                                                 
            
        end

        % Value changed function: VelocitymsSpinner
        function VelocitymsSpinnerValueChanged(app, event)
            value = app.VelocitymsSpinner.Value;
            
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
             %rotating up
             [errorCode]=app.sim.simxSetObjectOrientation(app.clientID,app.TargetSrvID,app.SpacecraftID,[app.TargetSrvOri(1)-app.AngleradSpinner.Value app.TargetSrvOri(2) app.TargetSrvOri(3)],app.sim.simx_opmode_oneshot);

             
            [res, app.TargetSrvOri] = app.sim.simxGetObjectOrientation(app.clientID, app.TargetSrvID, app.SpacecraftID,app.sim.simx_opmode_blocking ); 
             for i=1:6
                [res,app.j(i)]=app.sim.simxGetJointPosition(app.clientID,app.h(i),app.sim.simx_opmode_oneshot_wait);
             end
             
             updateImages(app); 
             getPosition(app);
             getAngle(app);
             getJointAngle(app);
             
             figure(1)
             app.Rob.plot(app.j);
             
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
             %rotating down
             [errorCode]=app.sim.simxSetObjectOrientation(app.clientID,app.TargetSrvID,app.SpacecraftID,[app.TargetSrvOri(1)+app.AngleradSpinner.Value app.TargetSrvOri(2) app.TargetSrvOri(3)],app.sim.simx_opmode_oneshot);

             [res, app.TargetSrvOri] = app.sim.simxGetObjectOrientation(app.clientID, app.TargetSrvID, app.SpacecraftID,app.sim.simx_opmode_blocking ); 
             for i=1:6
                [res,app.j(i)]=app.sim.simxGetJointPosition(app.clientID,app.h(i),app.sim.simx_opmode_oneshot_wait);
             end
             
             updateImages(app); 
             getPosition(app);
             getAngle(app);
             getJointAngle(app);
             
              figure(1)
             app.Rob.plot(app.j);
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
             %rotating up
             [errorCode]=app.sim.simxSetObjectOrientation(app.clientID,app.TargetSrvID,app.SpacecraftID,[app.TargetSrvOri(1) app.TargetSrvOri(2)-app.AngleradSpinner.Value app.TargetSrvOri(3)],app.sim.simx_opmode_oneshot);

             [res, app.TargetSrvOri] = app.sim.simxGetObjectOrientation(app.clientID, app.TargetSrvID, app.SpacecraftID,app.sim.simx_opmode_blocking ); 
              for i=1:6
                [res,app.j(i)]=app.sim.simxGetJointPosition(app.clientID,app.h(i),app.sim.simx_opmode_oneshot_wait);
              end
              
             updateImages(app);              
             getPosition(app);
             getAngle(app);
             getJointAngle(app);
             
             figure(1)
             app.Rob.plot(app.j);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
             [errorCode]=app.sim.simxSetObjectOrientation(app.clientID,app.TargetSrvID,app.SpacecraftID,[app.TargetSrvOri(1) app.TargetSrvOri(2)+app.AngleradSpinner.Value app.TargetSrvOri(3)],app.sim.simx_opmode_oneshot);

             [res, app.TargetSrvOri] = app.sim.simxGetObjectOrientation(app.clientID, app.TargetSrvID, app.SpacecraftID,app.sim.simx_opmode_blocking ); 
              for i=1:6
                [res,app.j(i)]=app.sim.simxGetJointPosition(app.clientID,app.h(i),app.sim.simx_opmode_oneshot_wait);
             end
             
             updateImages(app); 
             getPosition(app);
             getAngle(app);
             getJointAngle(app);
             
             figure(1)
             app.Rob.plot(app.j);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
             [errorCode]=app.sim.simxSetObjectOrientation(app.clientID,app.TargetSrvID,app.SpacecraftID,[app.TargetSrvOri(1) app.TargetSrvOri(2) app.TargetSrvOri(3)+app.AngleradSpinner.Value],app.sim.simx_opmode_oneshot);

             [res, app.TargetSrvOri] = app.sim.simxGetObjectOrientation(app.clientID, app.TargetSrvID, app.SpacecraftID,app.sim.simx_opmode_blocking ); 
              for i=1:6
                [res,app.j(i)]=app.sim.simxGetJointPosition(app.clientID,app.h(i),app.sim.simx_opmode_oneshot_wait);
             end
             
             updateImages(app); 
             getPosition(app);
             getAngle(app);
             getJointAngle(app);
             
             figure(1)
             app.Rob.plot(app.j);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
             [errorCode]=app.sim.simxSetObjectOrientation(app.clientID,app.TargetSrvID,app.SpacecraftID,[app.TargetSrvOri(1) app.TargetSrvOri(2) app.TargetSrvOri(3)-app.AngleradSpinner.Value],app.sim.simx_opmode_oneshot);

             [res, app.TargetSrvOri] = app.sim.simxGetObjectOrientation(app.clientID, app.TargetSrvID, app.SpacecraftID,app.sim.simx_opmode_blocking ); 
              for i=1:6
                [res,app.j(i)]=app.sim.simxGetJointPosition(app.clientID,app.h(i),app.sim.simx_opmode_oneshot_wait);
              end
              
             updateImages(app); 
             getPosition(app);
             getAngle(app);
             getJointAngle(app);
             
             figure(1)
             app.Rob.plot(app.j);
        end

        % Value changed function: AngleradSpinner
        function AngleradSpinnerValueChanged(app, event)
            value = app.AngleradSpinner.Value;
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            %key = event.Key;              
            if strcmp(app.KeyboardSwitch.Value, 'On')
                
                app.Label.Text = event.Key
                switch event.Key
                        case 'uparrow'
                            ButtonPushed(app);
                            
                        case 'downarrow'
                            Button_4Pushed(app);
                            
                        case 'leftarrow'
                            Button_3Pushed(app);
                            
                        case 'rightarrow'
                            Button_2Pushed(app);
                            
                        case 'w'
                            Button_5Pushed(app);
                        
                        %go backward on y axis  
                        case 's'
                            Button_6Pushed(app);
                            
                        case 'q'
                            Button_7Pushed(app);
        
                        case 'e'
                            Button_9Pushed(app);
                            
                        case 'a'
                            Button_12Pushed(app);
                            
                        case 'd'
                            Button_11Pushed(app);
                            
                        case 'z'
                            Button_10Pushed(app);
                            
                        case 'c'
                            Button_8Pushed(app);
                            
                    otherwise
                        app.Label.Text =  ['Invalid Input',newline,'Please refer User Guide.'];
                end                           
            end
        end

        % Value changed function: KeyboardSwitch
        function KeyboardSwitchValueChanged(app, event)
            value = app.KeyboardSwitch.Value;
            if strcmp(value, 'On')
                app.Label.BackgroundColor = [0.76,0.92,0.96];
                %UIFigureKeyPress(app);
                
            else
               app.Label.BackgroundColor = [0.94,0.94,0.94];
               app.Label.Text = "";
            end
        end

        % Value changed function: UserGuideButton
        function UserGuideButtonValueChanged(app, event)
            value = app.UserGuideButton.Value;
            Userguide
        end

        % Value changed function: VelocitymsSpinner_2
        function VelocitymsSpinner_2ValueChanged(app, event)
            value = app.VelocitymsSpinner_2.Value;

        end

        % Value changed function: JoystickSwitch
        function JoystickSwitchValueChanged(app, event)
            value = app.JoystickSwitch.Value;
           if  strcmp(value, 'On')
                app.Label_3.BackgroundColor = [0.76,0.92,0.96];
                 app.ID = 1;
                 
                % Create joystick variable
                app.joy=vrjoystick(app.ID);
                app.Y = axis(app.joy, 2);
                app.X = axis(app.joy, 1);
                while true
                     if button(app.joy,5)   
                        app.Label_3.Text = "Go Up";
                        ButtonPushed(app, event);                                              
                                                
                     elseif button(app.joy,4)
                        app.Label_3.Text = "Go Left";
                        Button_3Pushed(app, event);                                        
                                                
                    elseif button(app.joy,6)
                        app.Label_3.Text = "Go Right";
                        Button_2Pushed(app, event);
                        %updateImages(app);                        
                        
                    elseif button(app.joy,7)
                        app.Label_3.Text = "Go Down";
                        Button_4Pushed(app, event);
                        %updateImages(app);
                        
                    elseif (axis(app.joy,1) + 0.3 < app.X)
                        %display(axis(app.joy,1))
                        app.Label_3.Text = "Go Forward";
                         Button_5Pushed(app, event);
                         continue
                        %updateImages(app);  
                        
                    elseif (axis(app.joy,1) -0.3> app.X)
                         app.Label_3.Text = "Go Backward";
                         Button_6Pushed(app, event);
                         continue
%                         %updateImages(app);   
                        
                    elseif button(app.joy,8)
                        app.Label_3.Text = "Rotate Up";
                        Button_7Pushed(app, event);
                        %updateImages(app);  
                        
                    elseif button(app.joy,9)
                        app.Label_3.Text = "Rotate Left";
                        Button_10Pushed(app, event);
                        %updateImages(app);   
                        
                    elseif button(app.joy,10)
                        app.Label_3.Text = "Rotate Down";
                        Button_9Pushed(app, event);
                        %updateImages(app);  
                        
                    elseif button(app.joy,11)
                        app.Label_3.Text = "Rotate Right";
                        Button_8Pushed(app, event);
                        %updateImages(app); 
                        
                    elseif button(app.joy,2)
                        app.Label_3.Text = "Rotate AntiClockwise";
                        Button_12Pushed(app, event);
                        %updateImages(app);   
                        
                    elseif button(app.joy, 1)
                        %value = app.GripperSwitch.Value;
            
                         if (app.GripperSwitch_2.Value == "Close")
                           app.Lamp.Color = 'g';
                           %res=app.sim.simxSetJointTargetVelocity(app.clientID,app.EndGripper,app.VelocitymsSpinner_2.Value,app.sim.simx_opmode_oneshot);
                           res=app.sim.simxSetJointForce(app.clientID, app.EndGripper, 300, app.sim.simx_opmode_oneshot_wait); 
                           res=app.sim.simxSetJointTargetVelocity(app.clientID,app.EndGripper,0.005,app.sim.simx_opmode_blocking);
                           [res app.Force] = app.sim.simxGetJointForce(app.clientID, app.EndGripper, app.sim.simx_opmode_oneshot_wait);
                
                           updateImage(app);
                           app.Label_2.Text = sprintf('Gripping torque is %f N', app.Force);
                           app.Label_3.Text = "Open"; 
                           app.GripperSwitch_2.Value = "Open";
                           
                         elseif (app.GripperSwitch_2.Value == "Open")
                             
                          app.Lamp.Color = 'r';
                          %res=app.sim.simxSetJointTargetVelocity(app.clientID,app.EndGripper,(app.VelocitymsSpinner_2.Value*-1),app.sim.simx_opmode_oneshot);
                          res=app.sim.simxSetJointForce(app.clientID, app.EndGripper, 300, app.sim.simx_opmode_oneshot_wait); 
                          res=app.sim.simxSetJointTargetVelocity(app.clientID,app.EndGripper,-0.005,app.sim.simx_opmode_blocking);
                          [res app.Force] = app.sim.simxGetJointForce(app.clientID, app.EndGripper, app.sim.simx_opmode_oneshot_wait);
                          
                          updateImage(app);
                          app.Label_2.Text = sprintf('Gripping torque is %f N', app.Force);
                          app.Label_3.Text = "Open"; 
                          app.GripperSwitch.Value = "Open";
                          
                        end               


                     elseif button(app.joy,12)
                        app.Label_3.Text = "Rotate Clockwise";
                        Button_11Pushed(app, event);
                        %updateImages(app); 


                     elseif button(app.joy,3)
                       app.JoystickSwitch.Value = 'Off';
                       app.Label_3.BackgroundColor = [0.94,0.94,0.94];
                       app.Label_3.Text = "";               
                       % close(app.joy);
                       break                       
                         
                     end                                   
                  
                end
                    
%             elseif strcmp(value, 'Off')
%                 app.ID = 1;
%                 % Create joystick variable
%                 app.joy=vrjoystick(app.ID);
%                 if button(app.joy,3)
%                    app.JoystickSwitch.Value = 'On';
%                    app.Label_3.BackgroundColor = [0.76,0.92,0.96];
%                 end
                
            end
            
        end

        % Value changed function: GripperSwitch_2
        function GripperSwitch_2ValueChanged(app, event)
            value = app.GripperSwitch_2.Value;
            
            if strcmp(value, 'Open')
                app.Lamp.Color = 'g';
               %res=app.sim.simxSetJointTargetVelocity(app.clientID,app.EndGripper,app.VelocitymsSpinner_2.Value,app.sim.simx_opmode_oneshot);
               res=app.sim.simxSetJointForce(app.clientID, app.EndGripper, 300, app.sim.simx_opmode_oneshot_wait); 
               res=app.sim.simxSetJointTargetVelocity(app.clientID,app.EndGripper,0.005,app.sim.simx_opmode_blocking);
                [res app.Force] = app.sim.simxGetJointForce(app.clientID, app.EndGripper, app.sim.simx_opmode_oneshot_wait);
                
                %updateImage(app);
                app.Label_2.Text = sprintf('Gripping torque is %f N', app.Force);
                
            elseif strcmp(value, 'Close')
                app.Lamp.Color = 'r';
                %res=app.sim.simxSetJointTargetVelocity(app.clientID,app.EndGripper,(app.VelocitymsSpinner_2.Value*-1),app.sim.simx_opmode_oneshot);
                res=app.sim.simxSetJointForce(app.clientID, app.EndGripper, 300, app.sim.simx_opmode_oneshot_wait); 
                res=app.sim.simxSetJointTargetVelocity(app.clientID,app.EndGripper,-0.005,app.sim.simx_opmode_blocking);
                [res app.Force] = app.sim.simxGetJointForce(app.clientID, app.EndGripper, app.sim.simx_opmode_oneshot_wait);
                app.Label_2.Text = sprintf('Gripping torque is %f N', app.Force);
                %updateImage(app);              
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UserOutputsPanel = uipanel(app.UIFigure);
            app.UserOutputsPanel.Title = 'User Outputs';
            app.UIAxes = uiaxes(app.UserOutputsPanel);
            title(app.UIAxes, 'Image (RGB)')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes_2 = uiaxes(app.UserOutputsPanel);
            title(app.UIAxes_2, 'Depth Map')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.EndEffectorPositionMetrePanel = uipanel(app.UserOutputsPanel);
            app.EndEffectorPositionMetrePanel.Title = 'End Effector Position (Metre)';
            app.XPositionEditFieldLabel = uilabel(app.EndEffectorPositionMetrePanel);
            app.XPositionEditFieldLabel.Text = 'X Position';
            app.XPositionEditField = uieditfield(app.EndEffectorPositionMetrePanel, 'numeric');
            app.XPositionEditField.Editable = 'off';
            app.YPositionEditFieldLabel = uilabel(app.EndEffectorPositionMetrePanel);
            app.YPositionEditFieldLabel.Text = 'Y Position';
            app.YPositionEditField = uieditfield(app.EndEffectorPositionMetrePanel, 'numeric');
            app.YPositionEditField.Editable = 'off';
            app.ZPositionEditFieldLabel = uilabel(app.EndEffectorPositionMetrePanel);
            app.ZPositionEditFieldLabel.Text = 'Z Position';
            app.ZPositionEditField = uieditfield(app.EndEffectorPositionMetrePanel, 'numeric');
            app.ZPositionEditField.Editable = 'off';
            app.wrtSpacecraftLabel = uilabel(app.EndEffectorPositionMetrePanel);
            app.wrtSpacecraftLabel.Text = '(w.r.t  Spacecraft )';
            app.EndEffectorAnglesRadianPanel = uipanel(app.UserOutputsPanel);
            app.EndEffectorAnglesRadianPanel.Title = 'End Effector Angles (Radian)';
            app.PitchbetaEditFieldLabel = uilabel(app.EndEffectorAnglesRadianPanel);
            app.PitchbetaEditFieldLabel.Text = 'Pitch (beta)';
            app.PitchbetaEditField = uieditfield(app.EndEffectorAnglesRadianPanel, 'numeric');
            app.PitchbetaEditField.Editable = 'off';
            app.YawgammaEditFieldLabel = uilabel(app.EndEffectorAnglesRadianPanel);
            app.YawgammaEditFieldLabel.Text = 'Yaw (gamma)';
            app.YawgammaEditField = uieditfield(app.EndEffectorAnglesRadianPanel, 'numeric');
            app.YawgammaEditField.Editable = 'off';
            app.wrtSpacecraftLabel_2 = uilabel(app.EndEffectorAnglesRadianPanel);
            app.wrtSpacecraftLabel_2.Text = '(w.r.t  Spacecraft )';
            app.RollalphaEditFieldLabel = uilabel(app.EndEffectorAnglesRadianPanel);
            app.RollalphaEditFieldLabel.Text = 'Roll (alpha)';
            app.RollalphaEditField = uieditfield(app.EndEffectorAnglesRadianPanel, 'numeric');
            app.RollalphaEditField.Editable = 'off';
            app.ArmJointAnglesRadPanel = uipanel(app.UserOutputsPanel);
            app.ArmJointAnglesRadPanel.Title = 'Arm Joint Angles (Rad)';
            app.theta1EditFieldLabel = uilabel(app.ArmJointAnglesRadPanel);
            app.theta1EditFieldLabel.Text = 'theta 1';
            app.theta1EditField = uieditfield(app.ArmJointAnglesRadPanel, 'numeric');
            app.theta1EditField.Editable = 'off';
            app.theta2EditFieldLabel = uilabel(app.ArmJointAnglesRadPanel);
            app.theta2EditFieldLabel.Text = 'theta 2';
            app.theta2EditField = uieditfield(app.ArmJointAnglesRadPanel, 'numeric');
            app.theta2EditField.Editable = 'off';
            app.theta4EditFieldLabel = uilabel(app.ArmJointAnglesRadPanel);
            app.theta4EditFieldLabel.Text = 'theta 4';
            app.theta4EditField = uieditfield(app.ArmJointAnglesRadPanel, 'numeric');
            app.theta4EditField.Editable = 'off';
            app.theta5EditFieldLabel = uilabel(app.ArmJointAnglesRadPanel);
            app.theta5EditFieldLabel.Text = 'theta 5';
            app.theta5EditField = uieditfield(app.ArmJointAnglesRadPanel, 'numeric');
            app.theta5EditField.Editable = 'off';
            app.theta6EditFieldLabel = uilabel(app.ArmJointAnglesRadPanel);
            app.theta6EditFieldLabel.Text = 'theta 6';
            app.theta6EditField = uieditfield(app.ArmJointAnglesRadPanel, 'numeric');
            app.theta6EditField.Editable = 'off';
            app.theta3EditFieldLabel = uilabel(app.ArmJointAnglesRadPanel);
            app.theta3EditFieldLabel.Text = 'theta 3';
            app.theta3EditField = uieditfield(app.ArmJointAnglesRadPanel, 'numeric');
            app.theta3EditField.Editable = 'off';
            app.UserInputsPanel = uipanel(app.UIFigure);
            app.UserInputsPanel.Title = 'User Inputs';
            app.EndEffectorRotationwrtSpacecraftPanel = uipanel(app.UserInputsPanel);
            app.EndEffectorRotationwrtSpacecraftPanel.Title = 'End Effector Rotation (w.r.t. Spacecraft)';
            app.Button_7 = uibutton(app.EndEffectorRotationwrtSpacecraftPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = 'rup.png';
            app.Button_7.Enable = 'off';
            app.Button_7.Text = {''; ''};
            app.Button_9 = uibutton(app.EndEffectorRotationwrtSpacecraftPanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Icon = 'rdown.png';
            app.Button_9.Enable = 'off';
            app.Button_9.Text = '';
            app.Button_12 = uibutton(app.EndEffectorRotationwrtSpacecraftPanel, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Icon = 'anticw.png';
            app.Button_12.Enable = 'off';
            app.Button_12.Text = '';
            app.Button_11 = uibutton(app.EndEffectorRotationwrtSpacecraftPanel, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Icon = 'cw.png';
            app.Button_11.Enable = 'off';
            app.Button_11.Text = '';
            app.Button_10 = uibutton(app.EndEffectorRotationwrtSpacecraftPanel, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Icon = 'rleft.png';
            app.Button_10.Enable = 'off';
            app.Button_10.Text = {''; ''};
            app.Button_8 = uibutton(app.EndEffectorRotationwrtSpacecraftPanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Icon = 'rright.png';
            app.Button_8.Enable = 'off';
            app.Button_8.Text = '';
            app.AngleradSpinner = uispinner(app.EndEffectorRotationwrtSpacecraftPanel);
            app.AngleradSpinner.Step = 0.01;
            app.AngleradSpinner.Limits = [0 3.14];
            app.AngleradSpinner.ValueChangedFcn = createCallbackFcn(app, @AngleradSpinnerValueChanged, true);
            app.AngleradSpinner.Enable = 'off';
            app.AngleradSpinner.Value = 0.01;
            app.AngleradLabel = uilabel(app.EndEffectorRotationwrtSpacecraftPanel);
            app.AngleradLabel.Enable = 'off';
            app.AngleradLabel.Text = 'Angle (rad)';
            app.EndEffectorDirectionwrtSpacecraftPanel = uipanel(app.UserInputsPanel);
            app.EndEffectorDirectionwrtSpacecraftPanel.Title = 'End Effector Direction (w.r.t Spacecraft)';
            app.Button = uibutton(app.EndEffectorDirectionwrtSpacecraftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'up.png';
            app.Button.Enable = 'off';
            app.Button.Tooltip = {'Go Up'; ''};
            app.Button.Text = '';
            app.Button_4 = uibutton(app.EndEffectorDirectionwrtSpacecraftPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'down.png';
            app.Button_4.Enable = 'off';
            app.Button_4.Tooltip = {'Go Down'};
            app.Button_4.Text = '';
            app.Button_3 = uibutton(app.EndEffectorDirectionwrtSpacecraftPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'left.png';
            app.Button_3.Enable = 'off';
            app.Button_3.Tooltip = {'Go Left'};
            app.Button_3.Text = '';
            app.Button_2 = uibutton(app.EndEffectorDirectionwrtSpacecraftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'right.png';
            app.Button_2.Enable = 'off';
            app.Button_2.Tooltip = {'Go Right '};
            app.Button_2.Text = '';
            app.Button_5 = uibutton(app.EndEffectorDirectionwrtSpacecraftPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'forward.png';
            app.Button_5.Enable = 'off';
            app.Button_5.Tooltip = {'Go Forward'};
            app.Button_5.Text = {''; ''};
            app.Button_6 = uibutton(app.EndEffectorDirectionwrtSpacecraftPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = 'backward.png';
            app.Button_6.Enable = 'off';
            app.Button_6.Tooltip = {'Go Backward'};
            app.Button_6.Text = '';
            app.VelocitymsSpinner = uispinner(app.EndEffectorDirectionwrtSpacecraftPanel);
            app.VelocitymsSpinner.Step = 0.01;
            app.VelocitymsSpinner.Limits = [0 Inf];
            app.VelocitymsSpinner.ValueChangedFcn = createCallbackFcn(app, @VelocitymsSpinnerValueChanged, true);
            app.VelocitymsSpinner.Enable = 'off';
            app.VelocitymsSpinner.Value = 0.01;
            app.VelocitymsLabel = uilabel(app.EndEffectorDirectionwrtSpacecraftPanel);
            app.VelocitymsLabel.Enable = 'off';
            app.VelocitymsLabel.Text = 'Velocity (m/s)';
            app.GripperControlPanel = uipanel(app.UserInputsPanel);
            app.GripperControlPanel.Title = 'Gripper Control ';
            app.Lamp = uilamp(app.GripperControlPanel);
            app.Lamp.Enable = 'off';
            app.VelocitymsSpinner_2 = uispinner(app.GripperControlPanel);
            app.VelocitymsSpinner_2.Step = 0.001;
            app.VelocitymsSpinner_2.Limits = [0 0.01];
            app.VelocitymsSpinner_2.ValueChangedFcn = createCallbackFcn(app, @VelocitymsSpinner_2ValueChanged, true);
            app.VelocitymsSpinner_2.Enable = 'off';
            app.VelocitymsSpinner_2.Value = 0.01;
            app.Label_2 = uilabel(app.GripperControlPanel);
            app.Label_2.Text = '';
            app.VelocitymsLabel_2 = uilabel(app.GripperControlPanel);
            app.VelocitymsLabel_2.Enable = 'off';
            app.VelocitymsLabel_2.Text = 'Velocity (m/s)';
            app.GripperSwitch_2Label = uilabel(app.GripperControlPanel);
            app.GripperSwitch_2Label.Text = 'GripperSwitch';
            app.GripperSwitch_2 = uiswitch(app.GripperControlPanel, 'slider');
            app.GripperSwitch_2.Items = {'Close', 'Open'};
            app.GripperSwitch_2.ValueChangedFcn = createCallbackFcn(app, @GripperSwitch_2ValueChanged, true);
            app.GripperSwitch_2.Enable = 'off';
            app.GripperSwitch_2.Value = 'Close';
            app.ConnectionPanel = uipanel(app.UserInputsPanel);
            app.ConnectionPanel.Title = 'Connection';
            app.ConnectionSwitch = uiswitch(app.ConnectionPanel, 'rocker');
            app.ConnectionSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectionSwitchValueChanged, true);
            app.ConnectionSwitch.Tooltip = {'Turn On the switch and start the manipulation. '; 'Turn Off to get disconnected. '};
            app.Lamp_2 = uilamp(app.ConnectionPanel);
            app.InputDeviceButtonGroup = uibuttongroup(app.UserInputsPanel);
            app.InputDeviceButtonGroup.Title = 'Input Device';
            app.Label = uilabel(app.InputDeviceButtonGroup);
            app.Label.Enable = 'off';
            app.Label.Text = '';
            app.KeyboardSwitch = uiswitch(app.InputDeviceButtonGroup, 'slider');
            app.KeyboardSwitch.ValueChangedFcn = createCallbackFcn(app, @KeyboardSwitchValueChanged, true);
            app.KeyboardSwitch.Enable = 'off';
            app.KeyboardSwitch.Tooltip = {'Turn on to start control by using keyboard.'};
            app.KeyboardLabel = uilabel(app.InputDeviceButtonGroup);
            app.KeyboardLabel.Enable = 'off';
            app.KeyboardLabel.Text = 'Keyboard: ';
            app.JoystickSwitch = uiswitch(app.InputDeviceButtonGroup, 'slider');
            app.JoystickSwitch.ValueChangedFcn = createCallbackFcn(app, @JoystickSwitchValueChanged, true);
            app.JoystickSwitch.Enable = 'off';
            app.JoystickSwitch.Tooltip = {'Turn on to start control by using joystick.'};
            app.Label_3 = uilabel(app.InputDeviceButtonGroup);
            app.Label_3.Text = '';
            app.JoystickLabel = uilabel(app.InputDeviceButtonGroup);
            app.JoystickLabel.Enable = 'off';
            app.JoystickLabel.Text = 'Joystick: ';
            app.UserGuideButton = uibutton(app.UserInputsPanel, 'state');
            app.UserGuideButton.ValueChangedFcn = createCallbackFcn(app, @UserGuideButtonValueChanged, true);
            app.UserGuideButton.Tooltip = {'How to use the GUI.'};
            app.UserGuideButton.Text = 'User Guide';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app06092020keyboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end